classdef audio_play_and_record_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label_2            matlab.ui.control.Label
        BitEditField       matlab.ui.control.NumericEditField
        BitEditFieldLabel  matlab.ui.control.Label
        HzEditField_4      matlab.ui.control.NumericEditField
        HzLabel_2          matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        Button_3           matlab.ui.control.Button
        sEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        HzEditField_3      matlab.ui.control.NumericEditField
        HzLabel            matlab.ui.control.Label
        HzEditField_2      matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        HzEditField        matlab.ui.control.NumericEditField
        HzEditFieldLabel   matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_2           matlab.ui.control.RadioButton
        Button             matlab.ui.control.RadioButton
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Fs = app.HzEditField_4.Value; %采样率
            nbit = app.BitEditField.Value;%位深度
            recObj = audiorecorder(Fs,nbit,1);%录音对象
            recDuration = app.sEditField.Value;%录音持续时间，单位s
            f = app.HzEditField.Value;%固定频率，单位Hz
            sf_start = app.HzEditField_2.Value;%扫频起始频率，单位Hz
            sf_end = app.HzEditField_3.Value;%扫频结束频率，单位Hz
            %发声
            T = 1:recDuration*Fs;
            t = T/Fs;
            f_audioData = sin(2*pi*f*t);
            sf_audioData = chirp(t,sf_start,recDuration,sf_end);
            if app.Button.Value == 1
                audioData = f_audioData;
            end
            if app.Button_2.Value == 1
                audioData = sf_audioData;
            end
            sound(audioData,Fs,nbit)
            %录音
            app.Label_2.Text = "开始录音";
            recordblocking(recObj,recDuration);
            app.Label_2.Text = "结束录音";
            y = getaudiodata(recObj);
            plot(app.UIAxes,t,y,'r',t,audioData,'b')
            alpha(app.UIAxes,0.1)
            ylim(app.UIAxes,[-1 1])
            %傅里叶变换
            Y = fft(y);
            L = length(y);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            plot(app.UIAxes2,f,P1)
            xlim(app.UIAxes2,[0 max(find(P1>0.1*max(P1)))])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '时间/s')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '频率/Hz')
            ylabel(app.UIAxes2, '幅度')
            zlabel(app.UIAxes2, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '频率设置';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '单频';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '扫频';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '单频/Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField.ValueDisplayFormat = '%.0f';
            app.HzEditField.Value = 1000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '扫频起始频率/Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2.ValueDisplayFormat = '%.0f';
            app.HzEditField_2.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '扫频结束频率/Hz';
            app.HzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3.ValueDisplayFormat = '%.0f';
            app.HzEditField_3.Value = 1000;
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = '持续时间/s';
            app.sEditField = uieditfield(app.UIFigure, 'numeric');
            app.sEditField.Value = 2;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始检测';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '高级设置';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '采样率/Hz';
            app.HzEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_4.ValueDisplayFormat = '%.0f';
            app.HzEditField_4.Value = 44100;
            app.BitEditFieldLabel = uilabel(app.UIFigure);
            app.BitEditFieldLabel.Text = '位深度/Bit';
            app.BitEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitEditField.ValueDisplayFormat = '%.0f';
            app.BitEditField.Value = 24;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_play_and_record_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end