classdef audio_play_and_record_v1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CheckBox_2         matlab.ui.control.CheckBox
        CheckBox           matlab.ui.control.CheckBox
        Label_2            matlab.ui.control.Label
        BitEditField       matlab.ui.control.NumericEditField
        BitEditFieldLabel  matlab.ui.control.Label
        HzEditField_4      matlab.ui.control.NumericEditField
        HzLabel_2          matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        Button_3           matlab.ui.control.Button
        sEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        HzEditField_3      matlab.ui.control.NumericEditField
        HzLabel            matlab.ui.control.Label
        HzEditField_2      matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        HzEditField        matlab.ui.control.NumericEditField
        HzEditFieldLabel   matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_2           matlab.ui.control.RadioButton
        Button             matlab.ui.control.RadioButton
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Audio Play and Record';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Fs = app.HzEditField_4.Value; %采样率
            nbit = app.BitEditField.Value;%位深度
            recObj = audiorecorder(Fs,nbit,1);%录音对象
            recDuration = app.sEditField.Value;%录音持续时间，单位s
            T = 1:recDuration*Fs;
            t = T/Fs;
            if app.CheckBox.Value == 0                                
                f = app.HzEditField.Value;%固定频率，单位Hz
                sf_start = app.HzEditField_2.Value;%扫频起始频率，单位Hz
                sf_end = app.HzEditField_3.Value;%扫频结束频率，单位Hz
                %发声                
                if app.Button.Value == 1
                    f_audioData = sin(2*pi*f*t);
                    audioData = f_audioData;
                end
                if app.Button_2.Value == 1
                    sf_audioData = chirp(t,sf_start,recDuration,sf_end,'logarithmic');
                    audioData = sf_audioData;
                end
                sound(audioData,Fs,nbit)
                plot(app.UIAxes,t,audioData,'b')
            end
            %录音
            app.Label_2.Text = "开始录音";
            recordblocking(recObj,recDuration);
            app.Label_2.Text = "结束录音";
            y = getaudiodata(recObj);
            plot(app.UIAxes,t,y,'r')
            alpha(app.UIAxes,0.1)
            ylim(app.UIAxes,[-1 1])
            %傅里叶变换
            Y = fft(y);
            L = length(y);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            loglog(app.UIAxes2,f,P1)
            if app.CheckBox_2.Value == 1
                data = table(t',y,'VariableNames',{'time','y'});
                current_time = datetime('now');
                time_str = datestr(current_time, 'yyyy-mm-dd_HH-MM-SS');
                filename = [time_str, '.txt'];
                writetable(data, filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '时间/s')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '频率/Hz')
            ylabel(app.UIAxes2, '幅度')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [20 20000];
            app.UIAxes2.YLim = [0.0001 1];
            app.UIAxes2.XTick = [20 100 200 1000 10000];
            app.UIAxes2.XScale = 'log';
            app.UIAxes2.XTickLabel = {'20'; '100'; '200'; '1000'; '10000'};
            app.UIAxes2.YTick = [0.0001 0.001 0.01 0.1 1];
            app.UIAxes2.YScale = 'log';
            app.UIAxes2.YTickLabel = {'0.0001'; '0.001'; '0.01'; '0.1'; '1'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '频率设置';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '单频';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '扫频';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '单频/Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField.ValueDisplayFormat = '%.0f';
            app.HzEditField.Value = 1000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '扫频起始频率/Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2.ValueDisplayFormat = '%.0f';
            app.HzEditField_2.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '扫频结束频率/Hz';
            app.HzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3.ValueDisplayFormat = '%.0f';
            app.HzEditField_3.Value = 1000;
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = '持续时间/s';
            app.sEditField = uieditfield(app.UIFigure, 'numeric');
            app.sEditField.Value = 2;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始检测';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '高级设置';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '采样率/Hz';
            app.HzEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_4.ValueDisplayFormat = '%.0f';
            app.HzEditField_4.Value = 44100;
            app.BitEditFieldLabel = uilabel(app.UIFigure);
            app.BitEditFieldLabel.Text = '位深度/Bit';
            app.BitEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitEditField.ValueDisplayFormat = '%.0f';
            app.BitEditField.Value = 24;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '仅录音（若勾选，则不发声）';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '导出数据';
            app.CheckBox_2.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_play_and_record_v1_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end